classdef interface_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfaceREDv20UIFigure  matlab.ui.Figure
        Mapa2DPanel              matlab.ui.container.Panel
        UITable                  matlab.ui.control.Table
        Lamp                     matlab.ui.control.Lamp
        Switch                   matlab.ui.control.ToggleSwitch
        Image                    matlab.ui.control.Image
        Interfacev20Label        matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        RollSliderLabel          matlab.ui.control.Label
        RollSlider               matlab.ui.control.Slider
        PitchSliderLabel         matlab.ui.control.Label
        PitchSlider              matlab.ui.control.Slider
        YawSliderLabel           matlab.ui.control.Label
        YawSlider                matlab.ui.control.Slider
    end


    properties (Access = private)
        gx = 0
        gg = 0
        initial_coordinates=[38.736789 -9.137551 ];
        coords=[38.736789 -9.137551 ];
        alt=0;
        t=1;
        go= false
        A=0
        h=10; %height of cylinder
        deg=pi/180;
    end
    
    methods (Access = private)
        
        function display_map(app)
                while true
                    drawnow %to update button status
                    if app.go == true
                       pause(1) %to simulate the rate of data received
                       sizeA=size(app.A);
                       if app.t <= sizeA(2)
                           x=app.A(:,app.t);
                           %Plot new line of trajectory
                           hold(app.UIAxes,"on")
                           [new_lat, new_lon] = meters_to_coords(app, x);
                           plot3(app.UIAxes, x(2),x(3), x(4), 'o' )
                           
                           %Update trajectory
                           hold(app.gx,"on")
                           %geoplot(app.gx,[app.initial_coordinates(1)+x(2) app.coords(1)], [app.initial_coordinates(2)+x(3) app.coords(2)], 'Color','b', 'LineWidth', 2);
                           %app.coords(1)= app.initial_coordinates(1)+ x(2);
                           %app.coords(2)= app.initial_coordinates(2)+ x(3); 
                           geoplot(app.gx,[new_lat app.coords(1)], [new_lon app.coords(2)], 'Color','b', 'LineWidth', 2);
                           app.coords(1)= new_lat;
                           app.coords(2)= new_lon; 
                           
                           app.t=app.t+1; %disp(t);
                    
                           %Add new data to the table
                           oldData = get(app.UITable,'Data');
                           newRow={app.coords(1), app.coords(2), x(4), x(1)};
                           newData = [oldData; newRow];
                           set(app.UITable,'Data',newData);
                           
                           
                       end
                    end
                end
            end 
        
        function A=read_file(app)
            trajectory = fopen('Example_trajectory.txt','r');
            formatSpec = '%f,%f, %f, %f';
            sizeA = [4 Inf];
            A = fscanf(trajectory,formatSpec, sizeA);
            fclose(trajectory);
        end
        
        function [new_lat, new_long] = meters_to_coords(app, x)
             r_earth=6371000.0 ;
             new_lat  = app.initial_coordinates(1)  + (x(2) / r_earth) * (180 / pi);
             new_long = app.initial_coordinates(2) + (x(3) / r_earth) * (180 / pi) / cos(app.initial_coordinates(1) * pi/180);
           
        end
        
        function rodar(app, roll, pitch, yaw)
            [X,Y,Z]=cylinder(2);
            Z = Z*app.h;
            eul=[roll*app.deg pitch*app.deg yaw*app.deg];
            rotm = eul2rotm(eul);
            len=size(X);
            for i=1:len(2)
                B=[X(1,i), Y(1,i), Z(1,i)]* rotm;
                C=[X(2,i), Y(2,i), Z(2,i)]* rotm;
                
                X(1,i)=B(1);
                Y(1,i)=B(2);
                Z(1,i)=B(3);
                
                X(2,i)=C(1);
                Y(2,i)=C(2);
                Z(2,i)=C(3);
            end
                
            surf(app.UIAxes2,X,Y,Z);
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Create Table
            app.UITable.Data = {app.initial_coordinates(1), app.initial_coordinates(2), app.alt, app.t};
              
            %Create Maps
            app.gx=geoaxes(app.Mapa2DPanel,"Basemap","satellite"); 
            %app.gg = geoglobe(app.Mapa3DPanel); %only availabe in MatLab2020a
            app.gx.InnerPosition = app.gx.OuterPosition;
            plot3(app.UIAxes, 1, 1, 1)
            %xlim(app.UIAxes, [38.7 38.8]);
            %ylim(app.UIAxes, [-9.14 -9.13]);
            %zlim(app.UIAxes, [0 15]);
            xlim(app.UIAxes, [-360 0]);
            ylim(app.UIAxes, [0 140]);
            zlim(app.UIAxes, [0 3200]);
            %app.gg.Position = [0 0 1 1];
            
            %Plot Cylinder
            plot3(app.UIAxes2, 1, 1, 1);
            xlim(app.UIAxes2, [-10 10]);
            ylim(app.UIAxes2, [-10 10]);
            zlim(app.UIAxes2, [-10 10]);
            [X,Y,Z]=cylinder(2);
            Z = Z*app.h;
            surf(app.UIAxes2,X,Y,Z);
            
            %Plot Cone
            coneRadius=0.25;
            coneHeight=2;
            
            
                %Center map
            app.gx.MapCenter = app.initial_coordinates;
            app.gx.ZoomLevel = 15;
            
            %Read Trajectory
            app.A=read_file(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'On'
                    app.Lamp.Color = 'green';
                    app.go= true;
                    display_map(app)
                case 'Off'
                    app.Lamp.Color  = 'red';
                    app.go= false;
                    display_map(app)
            end
        end

        % Value changing function: RollSlider
        function RollSliderValueChanging(app, event)
            changingValue = event.Value;
            rodar(app,changingValue,app.PitchSlider.Value,app.YawSlider.Value)
        end

        % Value changing function: PitchSlider
        function PitchSliderValueChanging(app, event)
            changingValue = event.Value;
            rodar(app,app.RollSlider.Value,changingValue,app.YawSlider.Value)
        end

        % Value changing function: YawSlider
        function YawSliderValueChanging(app, event)
            changingValue = event.Value;
            rodar(app,app.RollSlider.Value,app.PitchSlider.Value, changingValue)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterfaceREDv20UIFigure = uifigure('Visible', 'off');
            app.InterfaceREDv20UIFigure.Name = 'Interface RED v2.0';
            app.Mapa2DPanel = uipanel(app.InterfaceREDv20UIFigure);
            app.Mapa2DPanel.Title = 'Mapa 2D';
            app.UITable = uitable(app.InterfaceREDv20UIFigure);
            app.UITable.ColumnName = {'Latitude'; 'Longitude'; 'Altitude'; 'Tempo'};
            app.UITable.RowName = {};
            app.Lamp = uilamp(app.InterfaceREDv20UIFigure);
            app.Switch = uiswitch(app.InterfaceREDv20UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Image = uiimage(app.InterfaceREDv20UIFigure);
            app.Image.ImageSource = 'RED.png';
            app.Interfacev20Label = uilabel(app.InterfaceREDv20UIFigure);
            app.Interfacev20Label.Text = 'Interface v2.0';
            app.UIAxes = uiaxes(app.InterfaceREDv20UIFigure);
            title(app.UIAxes, 'Trajectory')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2 = uiaxes(app.InterfaceREDv20UIFigure);
            title(app.UIAxes2, 'Rocket Status')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.RollSliderLabel = uilabel(app.InterfaceREDv20UIFigure);
            app.RollSliderLabel.Text = 'Roll';
            app.RollSlider = uislider(app.InterfaceREDv20UIFigure);
            app.RollSlider.Limits = [0 360];
            app.RollSlider.ValueChangingFcn = createCallbackFcn(app, @RollSliderValueChanging, true);
            app.PitchSliderLabel = uilabel(app.InterfaceREDv20UIFigure);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.InterfaceREDv20UIFigure);
            app.PitchSlider.Limits = [0 360];
            app.PitchSlider.ValueChangingFcn = createCallbackFcn(app, @PitchSliderValueChanging, true);
            app.YawSliderLabel = uilabel(app.InterfaceREDv20UIFigure);
            app.YawSliderLabel.Text = 'Yaw';
            app.YawSlider = uislider(app.InterfaceREDv20UIFigure);
            app.YawSlider.Limits = [0 360];
            app.YawSlider.ValueChangingFcn = createCallbackFcn(app, @YawSliderValueChanging, true);
            % Show the figure after all components are created
            app.InterfaceREDv20UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfaceREDv20UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfaceREDv20UIFigure)
        end
    end
end